classdef PassiveRadarApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AppStateMenu                    matlab.ui.container.Menu
        USRPMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ProcessingTab                   matlab.ui.container.Tab
        SignalsPanel                    matlab.ui.container.Panel
        SurveilanceIntegrationTimeSlider  matlab.ui.control.RangeSlider
        SurveilanceIntegrationTimeSliderLabel  matlab.ui.control.Label
        ReferenceIntegrationTimeSlider  matlab.ui.control.RangeSlider
        ReferenceIntegrationTimeSliderLabel  matlab.ui.control.Label
        PlottingPanel                   matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        CrossAmbiguityFunctionTab       matlab.ui.container.Tab
        CorrelationdelayLabel           matlab.ui.control.Label
        EditFieldLabel                  matlab.ui.control.Label
        FFTSizeEditFieldLabel           matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        CafVelocityRangeLabel           matlab.ui.control.Label
        CafDelayBackwardEditFieldLabel  matlab.ui.control.Label
        CafVelocityRangeEditField       matlab.ui.control.NumericEditField
        FFTSizeEditField                matlab.ui.control.NumericEditField
        CorrelationDelayEditField       matlab.ui.control.NumericEditField
        CafDelayForwardEditFieldLabel   matlab.ui.control.Label
        CafDelayBackwardEditField       matlab.ui.control.NumericEditField
        CafDelayForwardEditField        matlab.ui.control.NumericEditField
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        TimeSpectrumTab                 matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        AlgorithmsPanel                 matlab.ui.container.Panel
        Button                          matlab.ui.control.Button
        FilterFarButton                 matlab.ui.control.Button
        FilterFarOrderEditField         matlab.ui.control.NumericEditField
        FilterFarOrderEditFieldLabel    matlab.ui.control.Label
        FilterFarForgettingFactorEditField  matlab.ui.control.NumericEditField
        FilterFarForgettingFactorEditFieldLabel  matlab.ui.control.Label
        FilterNearForgettingFactorEditField  matlab.ui.control.NumericEditField
        FilterNearForgettingFactorEditFieldLabel  matlab.ui.control.Label
        FilterNearOrderEditField        matlab.ui.control.NumericEditField
        FilterNearOrderEditFieldLabel   matlab.ui.control.Label
        FilterNearButton                matlab.ui.control.Button
        LoggerWorkspacePanel            matlab.ui.container.Panel
        ClearLogsButton                 matlab.ui.control.Button
        LoggerTextArea                  matlab.ui.control.TextArea
        FilesPanel                      matlab.ui.container.Panel
        LoadSurveilanceButton           matlab.ui.control.Button
        LoadReferenceButton             matlab.ui.control.Button
        SignalsParametersPanel          matlab.ui.container.Panel
        SignalLengthEditField           matlab.ui.control.NumericEditField
        SignalLengthEditFieldLabel      matlab.ui.control.Label
        FcEditField                     matlab.ui.control.NumericEditField
        FcEditFieldLabel                matlab.ui.control.Label
        FsEditField                     matlab.ui.control.NumericEditField
        FsEditFieldLabel                matlab.ui.control.Label
        SimulationTab                   matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        MapTab                          matlab.ui.container.Tab
        CLEANTab                        matlab.ui.container.Tab
    end

    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
        Processor
        History

        config
    end
    
    methods (Access = private)
        
        function logMessage(app, text, mode)
            if nargin < 3
                mode = "INFO";
            end
        
            timestamp = char(datetime('now','Format','HH:mm:ss.SSS'));
            mode = upper(string(mode));

            switch mode
                case "INFO"
                    prefix = '[INFO] ';
                case "WARN"
                    prefix = '[WARN] ';
                case "ERROR"
                    prefix = '[ERROR]';
                case "SUCCESS"
                    prefix = '[ OK ] ';
                case "DEBUG"
                    prefix = '[DEBUG]';
                otherwise
                    prefix = sprintf('[%s]', mode);
            end

            fulltext = sprintf('%s %s %s', timestamp, prefix, text);

            currentLogs = app.LoggerTextArea.Value;

            app.LoggerTextArea.Value = [currentLogs; {fulltext}];
            
            scroll(app.LoggerTextArea,'bottom');
            disp(fulltext);
            drawnow limitrate;

        end

        function config = readConfig(app)
            configFile  = "config.json";
            config      = struct();
            p_signals   = struct();
            p_filters   = struct();
            p_caf       = struct();

            p_signals.fs                 = 10e6;
            p_signals.fc                 = 500e6;
            p_signals.length_s           = 1;   % in seconds [m]
            p_signals.numSamples         = p_signals.fs * p_signals.length_s;
            p_signals.ref_startSample    = 0;
            p_signals.ref_stopSample     = p_signals.numSamples;
            p_signals.surv_startSample   = 0;
            p_signals.surv_stopSample    = p_signals.numSamples;

            p_filters.orderNear          = 5;
            p_filters.forgettingFactNear = 0.9996;
            p_filters.orderFar           = 100;
            p_filters.forgettingFactFar  = 0.99999;

            p_caf.rangeStart_m  = 0;     % meters [m]
            p_caf.rangeStop_m   = 200;   % meters [m] 
            p_caf.velRange_ms   = 300;   % meters per seconds [m/s]
            p_caf.fftSize       = 2048;
            p_caf.corrRange     = 2000;  % samples 

            config.p_signals    = p_signals;
            config.p_filters    = p_filters;
            config.p_caf        = p_caf;

            if isfile(configFile)
                try
                    rawText = fileread(configFile);
                    fileCfg = jsondecode(rawText);

                    fields = fieldnames(fileCfg);

                    for i = 1:length(fields)
                        config.(fields{i}) = fileCfg.(fields{i});
                    end
                    app.logMessage('Configuration Loaded from config.json');

                catch ME
                    app.logMessage("Configuration loading failed", "WARN")
                end
            else 
                app.logMessage("No configuration file. Using default values");
            end
            
        end
    
        function setConfig(app)
            app.logMessage("Setting values from config");

            app.FsEditField.Value                       = app.config.p_signals.fs;
            app.FcEditField.Value                       = app.config.p_signals.fc;
            app.SignalLengthEditField.Value             = app.config.p_signals.length_s;

            ref_sliderStop                              = app.config.p_signals.ref_stopSample / app.config.p_signals.fs;
            surv_sliderStop                             = app.config.p_signals.surv_stopSample / app.config.p_signals.fs;
            app.ReferenceIntegrationTimeSlider.Limits   = [0, app.config.p_signals.length_s];
            app.ReferenceIntegrationTimeSlider.Value    = [app.config.p_signals.ref_startSample, ref_sliderStop];
            app.SurveilanceIntegrationTimeSlider.Limits = [0, app.config.p_signals.length_s];
            app.SurveilanceIntegrationTimeSlider.Value    = [app.config.p_signals.surv_startSample, surv_sliderStop];

            app.FilterFarForgettingFactorEditField.Value    = app.config.p_filters.forgettingFactFar;
            app.FilterNearForgettingFactorEditField.Value   = app.config.p_filters.forgettingFactNear;
            app.FilterFarOrderEditField.Value               = app.config.p_filters.orderFar;
            app.FilterNearOrderEditField.Value              = app.config.p_filters.orderNear;

            app.CafDelayBackwardEditField.Value         = app.config.p_caf.rangeStart_m;
            app.CafDelayForwardEditField.Value          = app.config.p_caf.rangeStop_m;
            app.CafVelocityRangeEditField.Value         = app.config.p_caf.velRange_ms;
            app.FFTSizeEditField.Value                  = app.config.p_caf.fftSize;
            app.CorrelationDelayEditField.Value         = app.config.p_caf.corrRange;
        end

        function [signal parameters] = loadSignalFromFile(app)
            [file, path] = uigetfile("Select reference signal");
            if isequal(file, 0) return; end
            filename = fullfile(path, file);

            try
                if endsWith(filename,'*.mat', 'IgnoreCase', true)
                   signal = struct2array(load(filename));
                else 
                    signal = read_complex_binary(filename);
                end
                
                app.logMessage(file);
            catch ME
                app.logMessage("Load Signal From File failes", "WARN");
                signal = -1;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.logMessage("Startup Function called");
            app.config = app.readConfig();
            app.setConfig();

            app.Processor = RadarProcessor(app.config.p_signals.fs, app.config.p_signals.fc);

        end

        % Button pushed function: LoadReferenceButton
        function LoadReferenceButtonPushed(app, event)
            app.logMessage("Load Reference Button Pushed");
            [x_ref, params] = app.loadSignalFromFile(app);
            if x_ref == -1 return; end

            app.Processor.x_ref = x_ref;
            app.logMessage(file);
            app.logMessage(path);
        end

        % Button pushed function: ClearLogsButton
        function ClearLogsButtonPushed(app, event)
            app.LoggerTextArea.Value = '';
        end

        % Button pushed function: LoadSurveilanceButton
        function LoadSurveilanceButtonPushed(app, event)
            app.logMessage("Load Surveillance Button Pushed");
            [x_surv, params] = app.loadSignalFromFile(app);
            if x_surv == -1 return; end

            app.Processor.x_surv = x_surv;
            app.logMessage(file);
            app.logMessage(path);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AppStateMenu = uimenu(app.UIFigure);
            app.AppStateMenu.Text = 'App State';
            app.USRPMenu = uimenu(app.UIFigure);
            app.USRPMenu.Text = 'USRP';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.SignalsParametersPanel = uipanel(app.ProcessingTab);
            app.SignalsParametersPanel.Title = 'Signals Parameters';
            app.FsEditFieldLabel = uilabel(app.SignalsParametersPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.SignalsParametersPanel, 'numeric');
            app.FcEditFieldLabel = uilabel(app.SignalsParametersPanel);
            app.FcEditFieldLabel.Text = 'Fc';
            app.FcEditField = uieditfield(app.SignalsParametersPanel, 'numeric');
            app.SignalLengthEditFieldLabel = uilabel(app.SignalsParametersPanel);
            app.SignalLengthEditFieldLabel.Text = 'Signal Length';
            app.SignalLengthEditField = uieditfield(app.SignalsParametersPanel, 'numeric');
            app.FilesPanel = uipanel(app.ProcessingTab);
            app.FilesPanel.Title = 'Files';
            app.LoadReferenceButton = uibutton(app.FilesPanel, 'push');
            app.LoadReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadReferenceButtonPushed, true);
            app.LoadReferenceButton.Text = 'Load Reference';
            app.LoadSurveilanceButton = uibutton(app.FilesPanel, 'push');
            app.LoadSurveilanceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSurveilanceButtonPushed, true);
            app.LoadSurveilanceButton.Text = 'Load Surveilance';
            app.LoggerWorkspacePanel = uipanel(app.ProcessingTab);
            app.LoggerWorkspacePanel.Title = 'Logger / Workspace';
            app.LoggerTextArea = uitextarea(app.LoggerWorkspacePanel);
            app.ClearLogsButton = uibutton(app.LoggerWorkspacePanel, 'push');
            app.ClearLogsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLogsButtonPushed, true);
            app.ClearLogsButton.Text = 'Clear Logs';
            app.AlgorithmsPanel = uipanel(app.ProcessingTab);
            app.AlgorithmsPanel.Title = 'Algorithms';
            app.FilterNearButton = uibutton(app.AlgorithmsPanel, 'push');
            app.FilterNearButton.Text = 'Filter Near';
            app.FilterNearOrderEditFieldLabel = uilabel(app.AlgorithmsPanel);
            app.FilterNearOrderEditFieldLabel.Text = 'Filter Near Order';
            app.FilterNearOrderEditField = uieditfield(app.AlgorithmsPanel, 'numeric');
            app.FilterNearForgettingFactorEditFieldLabel = uilabel(app.AlgorithmsPanel);
            app.FilterNearForgettingFactorEditFieldLabel.Text = {'Filter Near '; 'Forgetting Factor'};
            app.FilterNearForgettingFactorEditField = uieditfield(app.AlgorithmsPanel, 'numeric');
            app.FilterFarForgettingFactorEditFieldLabel = uilabel(app.AlgorithmsPanel);
            app.FilterFarForgettingFactorEditFieldLabel.Text = {'Filter Far '; 'Forgetting Factor'};
            app.FilterFarForgettingFactorEditField = uieditfield(app.AlgorithmsPanel, 'numeric');
            app.FilterFarOrderEditFieldLabel = uilabel(app.AlgorithmsPanel);
            app.FilterFarOrderEditFieldLabel.Text = 'Filter Far Order';
            app.FilterFarOrderEditField = uieditfield(app.AlgorithmsPanel, 'numeric');
            app.FilterFarButton = uibutton(app.AlgorithmsPanel, 'push');
            app.FilterFarButton.Text = 'Filter Far';
            app.Button = uibutton(app.AlgorithmsPanel, 'push');
            app.PlottingPanel = uipanel(app.ProcessingTab);
            app.PlottingPanel.Title = 'Plotting';
            app.TabGroup2 = uitabgroup(app.PlottingPanel);
            app.CrossAmbiguityFunctionTab = uitab(app.TabGroup2);
            app.CrossAmbiguityFunctionTab.Title = 'Cross Ambiguity Function';
            app.UIAxes = uiaxes(app.CrossAmbiguityFunctionTab);
            title(app.UIAxes, 'CAF')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes6 = uiaxes(app.CrossAmbiguityFunctionTab);
            title(app.UIAxes6, 'Cross Correlation')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.CafDelayForwardEditField = uieditfield(app.CrossAmbiguityFunctionTab, 'numeric');
            app.CafDelayBackwardEditField = uieditfield(app.CrossAmbiguityFunctionTab, 'numeric');
            app.CafDelayForwardEditFieldLabel = uilabel(app.CrossAmbiguityFunctionTab);
            app.CafDelayForwardEditFieldLabel.Text = 'Caf Delay Forward';
            app.CorrelationDelayEditField = uieditfield(app.CrossAmbiguityFunctionTab, 'numeric');
            app.FFTSizeEditField = uieditfield(app.CrossAmbiguityFunctionTab, 'numeric');
            app.CafVelocityRangeEditField = uieditfield(app.CrossAmbiguityFunctionTab, 'numeric');
            app.CafDelayBackwardEditFieldLabel = uilabel(app.CrossAmbiguityFunctionTab);
            app.CafDelayBackwardEditFieldLabel.Text = 'Caf Delay Backward';
            app.CafVelocityRangeLabel = uilabel(app.CrossAmbiguityFunctionTab);
            app.CafVelocityRangeLabel.Text = 'Caf Velocity Range (+/-)';
            app.EditField = uieditfield(app.CrossAmbiguityFunctionTab, 'numeric');
            app.FFTSizeEditFieldLabel = uilabel(app.CrossAmbiguityFunctionTab);
            app.FFTSizeEditFieldLabel.Text = 'FFT Size';
            app.EditFieldLabel = uilabel(app.CrossAmbiguityFunctionTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.CorrelationdelayLabel = uilabel(app.CrossAmbiguityFunctionTab);
            app.CorrelationdelayLabel.Text = {'Correlation'; 'Delay'};
            app.TimeSpectrumTab = uitab(app.TabGroup2);
            app.TimeSpectrumTab.Title = 'Time / Spectrum';
            app.GridLayout = uigridlayout(app.TimeSpectrumTab);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Reference Time')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Surveilance Time')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Reference Spectrum')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.GridLayout);
            title(app.UIAxes5, 'Surveilance Spectrum')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.SignalsPanel = uipanel(app.ProcessingTab);
            app.SignalsPanel.Title = 'Signals';
            app.ReferenceIntegrationTimeSliderLabel = uilabel(app.SignalsPanel);
            app.ReferenceIntegrationTimeSliderLabel.Text = {'Reference'; 'Integration'; 'Time'};
            app.ReferenceIntegrationTimeSlider = uislider(app.SignalsPanel, 'range');
            app.SurveilanceIntegrationTimeSliderLabel = uilabel(app.SignalsPanel);
            app.SurveilanceIntegrationTimeSliderLabel.Text = {'Surveilance'; 'Integration'; 'Time'};
            app.SurveilanceIntegrationTimeSlider = uislider(app.SignalsPanel, 'range');
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.Panel = uipanel(app.SimulationTab);
            app.Panel.Title = 'Panel';
            app.MapTab = uitab(app.TabGroup);
            app.MapTab.Title = 'Map';
            app.CLEANTab = uitab(app.TabGroup);
            app.CLEANTab.Title = 'CLEAN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PassiveRadarApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end