classdef PassiveRadarApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AppStateMenu                    matlab.ui.container.Menu
        USRPMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ProcessingTab                   matlab.ui.container.Tab
        ParamsLog                       matlab.ui.control.TextArea
        PlottingPanel                   matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        CrossAmbiguityFunctionTab       matlab.ui.container.Tab
        CorrelationdelayLabel           matlab.ui.control.Label
        CorrelationDelayEditField       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        CafVelocityRangeLabel           matlab.ui.control.Label
        CafVelocityRangeEditField       matlab.ui.control.NumericEditField
        FFTSizeEditFieldLabel           matlab.ui.control.Label
        FFTSizeEditField                matlab.ui.control.NumericEditField
        CafDelayBackwardEditFieldLabel  matlab.ui.control.Label
        CafDelayBackwardEditField       matlab.ui.control.NumericEditField
        CafDelayForwardEditFieldLabel   matlab.ui.control.Label
        CafDelayForwardEditField        matlab.ui.control.NumericEditField
        UISpectrumAxes                  matlab.ui.control.UIAxes
        UITimeAxes                      matlab.ui.control.UIAxes
        UIAxes_xcorr                    matlab.ui.control.UIAxes
        UIAxes_CAF                      matlab.ui.control.UIAxes
        AlgorithmsPanel                 matlab.ui.container.Panel
        PlotSpectrumCheckBox            matlab.ui.control.CheckBox
        PlotTimeCheckBox                matlab.ui.control.CheckBox
        AppStatusLabel                  matlab.ui.control.Label
        SaveFilenameEditField           matlab.ui.control.EditField
        SaveFilenameEditFieldLabel      matlab.ui.control.Label
        SaveCheckBox                    matlab.ui.control.CheckBox
        STEPButton                      matlab.ui.control.Button
        UseFilteringCheckBox            matlab.ui.control.CheckBox
        FilteringPanel                  matlab.ui.container.Panel
        ApplyFilterButton               matlab.ui.control.Button
        LatticePanel                    matlab.ui.container.Panel
        BackLengthEditField             matlab.ui.control.NumericEditField
        BackLengthEditFieldLabel        matlab.ui.control.Label
        BlockLengthEditField            matlab.ui.control.NumericEditField
        BlockLengthEditFieldLabel       matlab.ui.control.Label
        ForgettingFactorEditField       matlab.ui.control.NumericEditField
        ForgettingFactorEditFieldLabel  matlab.ui.control.Label
        FilterLengthEditField_Lattice   matlab.ui.control.NumericEditField
        FilterLengthEditField_2Label    matlab.ui.control.Label
        ECAPanel                        matlab.ui.container.Panel
        BackLengthEditField_ECA         matlab.ui.control.NumericEditField
        BackLengthEditField_2Label      matlab.ui.control.Label
        NumBlocksEditField              matlab.ui.control.NumericEditField
        NumBlocksEditFieldLabel         matlab.ui.control.Label
        BatchSizeEditField              matlab.ui.control.NumericEditField
        BatchSizeEditFieldLabel         matlab.ui.control.Label
        FilterLengthEditField           matlab.ui.control.NumericEditField
        FilterLengthEditFieldLabel      matlab.ui.control.Label
        ChoosefilterDropDown            matlab.ui.control.DropDown
        ChoosefilterDropDownLabel       matlab.ui.control.Label
        TrimSignalsCheckBox             matlab.ui.control.CheckBox
        UseWindowingCheckBox            matlab.ui.control.CheckBox
        STOPButton                      matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
        ModeDropDown                    matlab.ui.control.DropDown
        ModeDropDownLabel               matlab.ui.control.Label
        FilesRecordingModePanel         matlab.ui.container.Panel
        FileIndexEditField              matlab.ui.control.NumericEditField
        FileIndexEditFieldLabel         matlab.ui.control.Label
        LoadFolderButton                matlab.ui.control.Button
        LoadFilesButton                 matlab.ui.control.Button
        SignalsParametersPanel          matlab.ui.container.Panel
        SignalLengthEditField           matlab.ui.control.NumericEditField
        SignalLengthEditFieldLabel      matlab.ui.control.Label
        FcEditField                     matlab.ui.control.NumericEditField
        FcEditFieldLabel                matlab.ui.control.Label
        FsEditField                     matlab.ui.control.NumericEditField
        FsEditFieldLabel                matlab.ui.control.Label
        SimulationTab                   matlab.ui.container.Tab
        sim_StartButton                 matlab.ui.control.Button
        sim_TargetsPanel                matlab.ui.container.Panel
        Attenuation2dBEditField         matlab.ui.control.NumericEditField
        Attenuation2dBEditFieldLabel    matlab.ui.control.Label
        Attenuation1dBEditField         matlab.ui.control.NumericEditField
        Attenuation1dBEditFieldLabel    matlab.ui.control.Label
        Velocity2msEditField            matlab.ui.control.NumericEditField
        Velocity2msEditFieldLabel       matlab.ui.control.Label
        Velocity1msEditField            matlab.ui.control.NumericEditField
        Velocity1msEditFieldLabel       matlab.ui.control.Label
        Range2mEditField                matlab.ui.control.NumericEditField
        Range2mEditFieldLabel           matlab.ui.control.Label
        Range1mEditField                matlab.ui.control.NumericEditField
        Range1mEditFieldLabel           matlab.ui.control.Label
        sim_ParametersPanel             matlab.ui.container.Panel
        sim_IntegrationTimesEditField   matlab.ui.control.NumericEditField
        IntegrationTimesEditFieldLabel  matlab.ui.control.Label
        sim_FcEditField                 matlab.ui.control.NumericEditField
        FcEditField_2Label              matlab.ui.control.Label
        sim_FsEditField                 matlab.ui.control.NumericEditField
        FsEditField_2Label              matlab.ui.control.Label
        sim_SignalTypeDropDown          matlab.ui.control.DropDown
        SignalTypeDropDownLabel         matlab.ui.control.Label
        MapTab                          matlab.ui.container.Tab
        CLEANTab                        matlab.ui.container.Tab
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = public)
        Engine RadarEngine.RadarEngine
        DataProvider
        CafRecorder Recorder
        %Processor RadarProcessor
        Simulator RadarSimulator

        LoopTimer
        History
        Mode
        isProcessing

        config
        LogFileName
        PlotsFlag
    end

    properties (Access = private)
        processingFlags RadarEngine.ProcessingFlags
    end

    methods (Access = private)
        function startupLogger(app,logFileName)
            app.LogFileName = logFileName;
            fid = fopen(app.LogFileName, 'w');
            fprintf(fid, '=== PASSIVE RADAR APP MATLAB STARTED ===\n');
            fclose(fid);

            % if ispc
            %     cmd = sprintf('start powershell -NoExit -Command "Get-Content %s -Wait"', app.LogFileName);
            %     system(cmd);
            % elseif isunix
            %     cmd = sprintf('env LD_LIBRARY_PATH="" gnome-terminal -- bash -c "tail -f ''%s''" &', app.LogFileName);
            %     system(cmd)
            % end
        end
        function startupConfig(app)
            app.config = app.readConfig();
            app.setConfig();
        end
        function startupDataProvider(app)
            initialMode = app.ModeDropDown.Value;
            app.changeAppMode(initialMode);
        end
        function startupEngine(app)
            app.processingFlags = RadarEngine.ProcessingFlags();
            app.Engine = RadarEngine.RadarEngine(app.processingFlags);
            app.logMessage("Created Radar Engine");
        end

        function logMessage(app, text, mode)
            if nargin < 3
                mode = "INFO";
            end

            timestamp = char(datetime('now','Format','HH:mm:ss.SSS'));
            mode = upper(string(mode));

            switch mode
                case "INFO"
                    prefix  = '[INFO] ';
                case "WARN"
                    prefix  = '[WARN] ';
                case "ERROR"
                    prefix  = '[ERROR]';
                case "SUCCESS"
                    prefix  = '[ OK ] ';
                case "DEBUG"
                    prefix = '[DEBUG]';
                otherwise
                    prefix = sprintf('[%s]', mode);
            end

            fullText = sprintf('%s %s %s', timestamp, prefix, text);

            fid = fopen(app.LogFileName, "a");
            if fid ~=-1
                fprintf(fid, '%s\n', fullText);
            else
                return;
            end

            if mode == "ERROR"
                fprintf(2, '%s\n', fullText);
            else
                fprintf(1, '%s\n', fullText);
            end
            %currentLogs = app.LoggerTextArea.Value;

            %app.LoggerTextArea.Value = [currentLogs; {fullText}];
            %app.LoggerTextArea.FontColor = color;
            %scroll(app.LoggerTextArea,'bottom');
            %disp(fulltext);
            %drawnow limitrate;

        end
        function config = readConfig(app)
            configFile  = "config.json";
            config      = struct();
            p_signals   = struct();
            p_filters   = struct();
            p_caf       = struct();

            p_signals.fs                 = 10e6;
            p_signals.fc                 = 500e6;
            p_signals.length_s           = 1;   % in seconds [m]
            p_signals.numSamples         = p_signals.fs * p_signals.length_s;
            p_signals.ref_startSample    = 0;
            p_signals.ref_stopSample     = p_signals.numSamples;
            p_signals.surv_startSample   = 0;
            p_signals.surv_stopSample    = p_signals.numSamples;

            p_filters.orderNear          = 5;
            p_filters.forgettingFactNear = 0.9996;
            p_filters.orderFar           = 100;
            p_filters.forgettingFactFar  = 0.99999;

            p_caf.rangeStart_m  = 0;     % meters [m]
            p_caf.rangeStop_m   = 200;   % meters [m]
            p_caf.velRange_ms   = 300;   % meters per seconds [m/s]
            p_caf.fftSize       = 2048;
            p_caf.corrRange     = 2000;  % samples

            config.p_signals    = p_signals;
            config.p_filters    = p_filters;
            config.p_caf        = p_caf;

            if isfile(configFile)
                try
                    rawText = fileread(configFile);
                    fileCfg = jsondecode(rawText);

                    fields = fieldnames(fileCfg);

                    for i = 1:length(fields)
                        config.(fields{i}) = fileCfg.(fields{i});
                    end
                    app.logMessage('Configuration Loaded from config.json');

                catch ME
                    app.logMessage("Configuration loading failed", "WARN")
                end
            else
                app.logMessage("No configuration file. Using default values");
            end

        end
        function setConfig(app)
            app.logMessage("Setting values from config");

            app.FsEditField.Value                       = app.config.p_signals.fs;
            app.FcEditField.Value                       = app.config.p_signals.fc;
            app.SignalLengthEditField.Value             = app.config.p_signals.length_s;

            app.CafDelayBackwardEditField.Value         = app.config.p_caf.rangeStart_m;
            app.CafDelayForwardEditField.Value          = app.config.p_caf.rangeStop_m;
            app.CafVelocityRangeEditField.Value         = app.config.p_caf.velRange_ms;
            app.FFTSizeEditField.Value                  = app.config.p_caf.fftSize;
            app.CorrelationDelayEditField.Value         = app.config.p_caf.corrRange;

            app.LatticePanel.Visible = 'off';
            app.ECAPanel.Visible = "on";
        end

        % function syncUItoSimulator(app)
        %     if isempty(app.Simulator)
        %         app.Simulator = RadarSimulator();
        %         app.logMessage("Simulator object created");
        %     end
        %     cfgSig = struct;
        %     targets() = 0;
        %
        %     cfgSig.Type     = app.sim_SignalTypeDropDown.Value;
        %     cfgSig.Fs       = app.sim_FsEditField.Value;
        %     cfgSig.Fc       = app.sim_FcEditField.Value;
        %     cfgSig.inTime   = app.sim_IntegrationTimesEditField.Value;
        %
        %     app.Simulator.SigConfig = cfgSig;
        %
        %     app.Simulator.addTarget(RadarTarget(1, app))
        % end

        function changeAppMode(app, newMode)
            app.logMessage("Changing app to mode: " + upper(newMode));

            if ~isempty(app.LoopTimer) && isvalid(app.LoopTimer)
                stop(app.LoopTimer);
                app.logMessage("LoopTimer STOPPED");
            end
            app.Mode = newMode;
            switch app.Mode
                case "Recording"
                    app.DataProvider = FileDataProvider();
                case "Real-Time"

                    app.DataProvider = CppBufferDataProvider();
                otherwise
                    app.logMessage("Unknown Mode","WARN");
                    return;
            end
            app.logMessage("App mode changed succesfully", "SUCCESS");
        end

        function changeEngineFilter(app, newFilterName)
            app.logMessage("Changing Engine filter to: " + newFilterName);

            if ~isempty(app.LoopTimer) && isvalid(app.LoopTimer)
                stop(app.LoopTimer);
                app.logMessage("LoopTimer STOPPED");
            end

            filterParams = struct();
            switch newFilterName
                case "Lattice"
                    filterParams.FilterLength = app.FilterLengthEditField_Lattice.Value;
                    filterParams.ForgettingFactor = app.ForgettingFactorEditField.Value;
                    filterParams.BlockLength = app.BlockLengthEditField.Value;
                    filterParams.BackFilterLength = app.BackLengthEditField.Value;
                case {"ECA", "Fast ECA"}
                    filterParams.FilterLength = app.FilterLengthEditField.Value;
                    filterParams.BatchSize = app.BatchSizeEditField.Value;
                    filterParams.NumBlocks = app.NumBlocksEditField.Value;
                    filterParams.BackFilterLength = app.BackLengthEditField_ECA.Value;
            end

            app.Engine.setFilter(newFilterName, filterParams);
        end

        function processingStep(app)
            try
                [ref,surv,params, success, fileParams] = app.DataProvider.getNextChunk();
                if ~success
                    disp("Stopping Processing Loop");
                    stop(app.LoopTimer);
                    app.emergencyStop();
                    return;
                end
                app.logMessage(sprintf("Processed %d / %d files", app.DataProvider.CurrentIndex, app.DataProvider.numFiles));
                app.FileIndexEditField.Value = fileParams.fileIdx;
                app.FsEditField.Value = params.fs;
                app.FcEditField.Value = params(1).fc(1);
                app.SignalLengthEditField.Value = length(ref) / params(1).fs;
                app.Engine.setSignals(ref, surv);
                app.Engine.processSignals(params);

                [xcor, lags] =  xcorr(app.Engine.xSurv,app.Engine.xRef, 2000);
                plot(app.UIAxes_xcorr,lags,abs(xcor));

                if ~isempty(app.CafRecorder) && app.CafRecorder.isRecording()
                    app.CafRecorder.addFrame(app.Engine.CafMap, ...
                        app.Engine.filtParams, ...
                        app.Engine.RangeAxis, ...
                        app.Engine.DopplerAxis, ...
                        fileParams);
                end
                drawnow limitrate;
            catch ME
                app.emergencyStop();
                disp(ME.message);
            end
        end

        function updateFileProvider(app,paths)
            app.DataProvider.configure(paths,struct('DataSource', 'Is3p'))
        end

        function updateUIState(app, state)
            if nargin < 2
                state = "";
            end
            switch state
                case "Running"
                    app.FileIndexEditField.Enable = "off";
                    app.AppStatusLabel.Text = "Running";
                    app.AppStatusLabel.FontColor = 'r';
                case "Idle"
                    app.FileIndexEditField.Enable = "on";
                    app.AppStatusLabel.Text = "Idle";
                    app.AppStatusLabel.FontColor = 'g';
                otherwise
                    app.FileIndexEditField.Enable = "on";
                    app.AppStatusLabel.Text = "Idle";
                    app.AppStatusLabel.FontColor = 'g';
            end
        end

        function updateUI(app)
            imagesc(app.UIAxes_CAF, ...
                app.Engine.DopplerAxis, ...
                app.Engine.RangeAxis, ...
                app.Engine.CafMap);
            %fname = fullfile(string(datetime("now","Format","uuuu-MM-dd")), "cafMap");
            %save(["Savings/" fname],"app.UIAxes_CAF","-mat");
            %app.UIAxes_CAF.YDir = 'normal';
            axis(app.UIAxes_CAF,'xy');
            colormap(app.UIAxes_CAF,'jet'); colorbar(app.UIAxes_CAF);
            maxPeak = max(app.Engine.CafMap, [], 'all');
            meanPeax = mean(app.Engine.CafMap,"all");
            %dynamicRange = 50;
            if isnan(maxPeak) || isempty(maxPeak) || isnan(meanPeax) || isempty(meanPeax)
                return;
            end
            clim(app.UIAxes_CAF, [meanPeax, maxPeak]);

            xlabel(app.UIAxes_CAF, 'Przesunięcie Dopplera [Hz]');
            ylabel(app.UIAxes_CAF, 'Odległość bistatyczna [km]');
            colorbar(app.UIAxes_CAF);

            xSurv = app.Engine.xSurv;
            if app.PlotTimeCheckBox.Value
                plot(app.UITimeAxes,abs(xSurv));
            end
            if app.PlotSpectrumCheckBox.Value
                plot_spectrum(xSurv, app.FsEditField.Value, app.UISpectrumAxes);
            end

            app.updateParamsLog(app.Engine, app.FcEditField.Value);
            drawnow limitrate;
        end

        function updateParamsLog(app, engine, Fc)
            surv_raw = engine.xSurvRaw;
            p_ref = 10 * log10(mean(abs(engine.xRef).^2) + eps);
            p_surv_raw = 10 * log10(mean(abs(surv_raw).^2) + eps);
            p_surv_filt = 10 * log10(mean(abs(engine.xSurv).^2) + eps);

            cafMap = engine.CafMap;
            [maxPeak, linearIdx] = max(cafMap, [], 'all');
            [rIdx, dIdx] = ind2sub(size(cafMap), linearIdx);

            peakRange = engine.RangeAxis(rIdx);
            peakDoppler = engine.DopplerAxis(dIdx);

            c = 3e8;
            velocity_ms = (peakDoppler * c) / Fc;
            velocity_kmh = velocity_ms * 3.6;

            logText = sprintf([ ...
                '--- FRAME PARAMETERS ---\n', ...
                'Pow x_ref: %.2f dB\n', ...
                'Pow x_surv (Raw): %.2f dB\n', ...
                'Pow x_surv (Filtered): %.2f dB\n', ...
                'Filt Atten: %.2f dB\n', ...
                '------------------------\n', ...
                'CAF Max Peak: %.2f dB\n', ...
                'Caf Idxs [Range, Doppler]: [%d, %d]\n', ...
                'Range: %.2f km\n', ...
                'Doppler: %.2f Hz\n', ...
                'Vel: %.2f km/h'], ...
                p_ref, p_surv_raw, p_surv_filt, (p_surv_raw - p_surv_filt), ...
                maxPeak, rIdx, dIdx, peakRange, peakDoppler, velocity_kmh);

            app.ParamsLog.Value = logText;
            drawnow limitrate;
        end
        function emergencyStop(app)
            app.CafRecorder.stopRecording();
            app.updateUIState("Idle");
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all; clc;
            %app.LoggerTextArea.Editable = "off";
            app.startupLogger("radar_session.log");
            app.logMessage("Startup Function called");
            app.startupConfig();
            app.startupEngine();
            app.startupDataProvider();
            app.CafRecorder = Recorder();
            app.LoopTimer = timer(...
                'ExecutionMode', 'fixedSpacing', 'Period', 0.05, ...
                'TimerFcn', @(~,~) app.processingStep(), ...
                'ErrorFcn', @(~,~) app.emergencyStop());
            app.updateUIState();
            %app.Processor = RadarProcessor(app.config.p_signals.fs, app.config.p_signals.fc);
            %app.Simulator = RadarSimulator();

            addlistener(app.Engine, 'DataProcessed', @(~,~) app.updateUI());
            app.isProcessing = false;
        end

        % Button pushed function: LoadFilesButton
        function LoadFilesButtonPushed(app, event)
            app.logMessage("Loading signals from files...");
            typesFilter = {'*.bin'; '*.mat'; '*.dat'};
            [files, path] = uigetfile(typesFilter,"Choose raw signals",'MultiSelect','on');
            if isequal(files, 0)
                app.logMessage("Loading signals canceled");
                return;
            end

            if ischar(files)
                files = {files};
            end

            fullPaths = cell(1,length(files));
            for i = 1:length(files)
                fullPaths{i} = fullfile(path,files{i});
            end
            app.updateFileProvider(fullPaths);
            app.logMessage("Loaded " + length(fullPaths) + " files", "SUCCESS");
        end

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            app.logMessage("Loading signals from folder...");
            folderPath = uigetdir('', 'Choose recording folder');

            if isequal(folderPath, 0)
                app.logMessage("Loading folder canceled");
                return;
            end


            filePattern = fullfile(folderPath, '*.bin');
            recFiles = dir(filePattern);

            if isempty(recFiles)
                uialert(app.UIFigure, 'Choosed folder hase inproper files format (*.bin)', 'ERROR');
                return;
            end

            fullPaths = cell(1, length(recFiles));
            for i = 1:length(recFiles)
                fullPaths{i} = fullfile(folderPath, recFiles(i).name);
            end

            app.updateFileProvider(fullPaths);
            app.logMessage("Loaded " + length(fullPaths) + " files", "SUCCESS");
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if ~app.isProcessing
                if isempty(app.DataProvider)
                    uialert(app.UIFigure, "Choose app mode", "No mode choosed");
                    return;
                end
                if app.Mode == "Recording" && app.DataProvider.numFiles == 0
                    uialert(app.UIFigure, "There is no files to process", "Faulty DataProvider");
                    return;
                end

                app.isProcessing = true;
                app.updateUIState("Running");
                app.logMessage("Starting processing loop...");

                if strcmp(app.LoopTimer.Running, 'off')
                    if app.SaveCheckBox.Value
                        app.CafRecorder.startRecording(fullfile(pwd,"Files/",app.SaveFilenameEditField.Value), app.ChoosefilterDropDown.Value);
                    end
                    start(app.LoopTimer);
                end

            else
                return;
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            if app.isProcessing == true
                app.isProcessing = false;
                app.updateUIState("Idle");
                app.logMessage("Processing loop stopped");

                if strcmp(app.LoopTimer.Running, 'on')
                    app.CafRecorder.stopRecording();
                    stop(app.LoopTimer);
                end
            else
                uialert(app.UIFigure, "Processing is already stopped", "Timer is not running");
            end
        end

        % Button pushed function: sim_StartButton
        function sim_StartButtonPushed(app, event)
            app.logMessage("Running the simulation");

        end

        % Value changed function: sim_FsEditField
        function sim_FsEditFieldValueChanged(app, event)
            value = app.sim_FsEditField.Value;

        end

        % Value changed function: ModeDropDown
        function ModeDropDownValueChanged(app, event)
            selectedMode = app.ModeDropDown.Value;
            app.changeAppMode(selectedMode);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if strcmp(app.LoopTimer.Running, 'on')
                stop(app.LoopTimer);
            end
            delete(app)
        end

        % Value changed function: ChoosefilterDropDown
        function ChoosefilterDropDownValueChanged(app, event)
            value = app.ChoosefilterDropDown.Value;
            switch value
                case "Lattice"
                    app.ECAPanel.Visible = 'off';
                    app.LatticePanel.Visible = 'on';
                case {"ECA", "Fast ECA"}
                    app.LatticePanel.Visible = 'off';
                    app.ECAPanel.Visible = 'on';
            end
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            selectedFilter = app.ChoosefilterDropDown.Value;
            app.changeEngineFilter(selectedFilter);
        end

        % Value changed function: UseFilteringCheckBox
        function UseFilteringCheckBoxValueChanged(app, event)
            value = app.UseFilteringCheckBox.Value;
            app.processingFlags.useFilter = value;
            app.Engine.ProcessingFlags = app.processingFlags;
        end

        % Value changed function: UseWindowingCheckBox
        function UseWindowingCheckBoxValueChanged(app, event)
            value = app.UseWindowingCheckBox.Value;
            app.processingFlags.isWindow = value;
            app.Engine.ProcessingFlags = app.processingFlags;
        end

        % Value changed function: TrimSignalsCheckBox
        function TrimSignalsCheckBoxValueChanged(app, event)
            value = app.TrimSignalsCheckBox.Value;
            app.processingFlags.trimSignals = value;
            app.Engine.ProcessingFlags = app.processingFlags;
        end

        % Value changed function: CafDelayBackwardEditField, 
        % ...and 5 other components
        function CafDelayForwardEditFieldValueChanged(app, event)
            minRange = app.CafDelayBackwardEditField.Value;
            maxRange = app.CafDelayForwardEditField.Value;
            minDoppler = - app.CafVelocityRangeEditField.Value;
            maxDoppler = app.CafVelocityRangeEditField.Value;
            decFactor = 1;
            app.Engine.setCafParams(minRange,maxRange, minDoppler, maxDoppler,decFactor);
        end

        % Value changed function: FileIndexEditField
        function FileIndexEditFieldValueChanged(app, event)
            value = app.FileIndexEditField.Value;
            if app.DataProvider.numFiles > value
                app.DataProvider.CurrentIndex = value;
            end
        end

        % Button pushed function: STEPButton
        function STEPButtonPushed(app, event)
            app.processingStep();
        end

        % Value changed function: SaveCheckBox
        function SaveCheckBoxValueChanged(app, event)
            value = app.SaveCheckBox.Value;
            if value
                app.SaveFilenameEditField.Enable = "on";
            else
                app.SaveFilenameEditField.Enable = "off";
            end
        end

        % Value changed function: BackLengthEditField_ECA, 
        % ...and 3 other components
        function BackLengthEditField_ECAValueChanged(app, event)
            value = app.FilterLengthEditField.Value;
            app.changeEngineFilter(app.ChoosefilterDropDown.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AppStateMenu = uimenu(app.UIFigure);
            app.AppStateMenu.Text = 'App State';
            app.USRPMenu = uimenu(app.UIFigure);
            app.USRPMenu.Text = 'USRP';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.SignalsParametersPanel = uipanel(app.ProcessingTab);
            app.SignalsParametersPanel.Title = 'Signals Parameters';
            app.FsEditFieldLabel = uilabel(app.SignalsParametersPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.SignalsParametersPanel, 'numeric');
            app.FcEditFieldLabel = uilabel(app.SignalsParametersPanel);
            app.FcEditFieldLabel.Text = 'Fc';
            app.FcEditField = uieditfield(app.SignalsParametersPanel, 'numeric');
            app.SignalLengthEditFieldLabel = uilabel(app.SignalsParametersPanel);
            app.SignalLengthEditFieldLabel.Text = 'Signal Length';
            app.SignalLengthEditField = uieditfield(app.SignalsParametersPanel, 'numeric');
            app.FilesRecordingModePanel = uipanel(app.ProcessingTab);
            app.FilesRecordingModePanel.Title = 'Files (Recording Mode)';
            app.LoadFilesButton = uibutton(app.FilesRecordingModePanel, 'push');
            app.LoadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilesButtonPushed, true);
            app.LoadFilesButton.Text = 'Load Files';
            app.LoadFolderButton = uibutton(app.FilesRecordingModePanel, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.FileIndexEditFieldLabel = uilabel(app.FilesRecordingModePanel);
            app.FileIndexEditFieldLabel.Text = 'File Index';
            app.FileIndexEditField = uieditfield(app.FilesRecordingModePanel, 'numeric');
            app.FileIndexEditField.ValueChangedFcn = createCallbackFcn(app, @FileIndexEditFieldValueChanged, true);
            app.AlgorithmsPanel = uipanel(app.ProcessingTab);
            app.AlgorithmsPanel.Title = 'Algorithms';
            app.ModeDropDownLabel = uilabel(app.AlgorithmsPanel);
            app.ModeDropDownLabel.Text = 'Mode';
            app.ModeDropDown = uidropdown(app.AlgorithmsPanel);
            app.ModeDropDown.Items = {'Recording', 'Real-Time'};
            app.ModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModeDropDownValueChanged, true);
            app.ModeDropDown.Value = 'Recording';
            app.STARTButton = uibutton(app.AlgorithmsPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.AlgorithmsPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.UseWindowingCheckBox = uicheckbox(app.AlgorithmsPanel);
            app.UseWindowingCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseWindowingCheckBoxValueChanged, true);
            app.UseWindowingCheckBox.Text = 'Use Windowing';
            app.TrimSignalsCheckBox = uicheckbox(app.AlgorithmsPanel);
            app.TrimSignalsCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrimSignalsCheckBoxValueChanged, true);
            app.TrimSignalsCheckBox.Text = 'Trim Signals';
            app.ChoosefilterDropDownLabel = uilabel(app.AlgorithmsPanel);
            app.ChoosefilterDropDownLabel.Text = 'Choose filter';
            app.ChoosefilterDropDown = uidropdown(app.AlgorithmsPanel);
            app.ChoosefilterDropDown.Items = {'Lattice', 'ECA', 'Fast ECA'};
            app.ChoosefilterDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoosefilterDropDownValueChanged, true);
            app.ChoosefilterDropDown.Value = 'Fast ECA';
            app.FilteringPanel = uipanel(app.AlgorithmsPanel);
            app.FilteringPanel.Title = 'Filtering Panel';
            app.ECAPanel = uipanel(app.FilteringPanel);
            app.ECAPanel.Title = 'ECA';
            app.FilterLengthEditFieldLabel = uilabel(app.ECAPanel);
            app.FilterLengthEditFieldLabel.Text = 'Filter Length';
            app.FilterLengthEditField = uieditfield(app.ECAPanel, 'numeric');
            app.FilterLengthEditField.ValueChangedFcn = createCallbackFcn(app, @BackLengthEditField_ECAValueChanged, true);
            app.FilterLengthEditField.Value = 2200;
            app.BatchSizeEditFieldLabel = uilabel(app.ECAPanel);
            app.BatchSizeEditFieldLabel.Text = 'Batch Size';
            app.BatchSizeEditField = uieditfield(app.ECAPanel, 'numeric');
            app.BatchSizeEditField.ValueChangedFcn = createCallbackFcn(app, @BackLengthEditField_ECAValueChanged, true);
            app.BatchSizeEditField.Value = 800000;
            app.NumBlocksEditFieldLabel = uilabel(app.ECAPanel);
            app.NumBlocksEditFieldLabel.Text = 'Num Blocks';
            app.NumBlocksEditField = uieditfield(app.ECAPanel, 'numeric');
            app.NumBlocksEditField.ValueChangedFcn = createCallbackFcn(app, @BackLengthEditField_ECAValueChanged, true);
            app.BackLengthEditField_2Label = uilabel(app.ECAPanel);
            app.BackLengthEditField_2Label.Text = 'Back Length';
            app.BackLengthEditField_ECA = uieditfield(app.ECAPanel, 'numeric');
            app.BackLengthEditField_ECA.ValueChangedFcn = createCallbackFcn(app, @BackLengthEditField_ECAValueChanged, true);
            app.BackLengthEditField_ECA.Value = 1000;
            app.LatticePanel = uipanel(app.FilteringPanel);
            app.LatticePanel.Title = 'Lattice';
            app.LatticePanel.Visible = 'off';
            app.FilterLengthEditField_2Label = uilabel(app.LatticePanel);
            app.FilterLengthEditField_2Label.Text = 'Filter Length';
            app.FilterLengthEditField_Lattice = uieditfield(app.LatticePanel, 'numeric');
            app.FilterLengthEditField_Lattice.Value = 600;
            app.ForgettingFactorEditFieldLabel = uilabel(app.LatticePanel);
            app.ForgettingFactorEditFieldLabel.Text = {'Forgetting'; 'Factor'};
            app.ForgettingFactorEditField = uieditfield(app.LatticePanel, 'numeric');
            app.ForgettingFactorEditField.Value = 0.99999;
            app.BlockLengthEditFieldLabel = uilabel(app.LatticePanel);
            app.BlockLengthEditFieldLabel.Text = 'Block Length';
            app.BlockLengthEditField = uieditfield(app.LatticePanel, 'numeric');
            app.BlockLengthEditField.Value = 8192;
            app.BackLengthEditFieldLabel = uilabel(app.LatticePanel);
            app.BackLengthEditFieldLabel.Text = 'Back Length';
            app.BackLengthEditField = uieditfield(app.LatticePanel, 'numeric');
            app.BackLengthEditField.Value = 100;
            app.ApplyFilterButton = uibutton(app.FilteringPanel, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.UseFilteringCheckBox = uicheckbox(app.AlgorithmsPanel);
            app.UseFilteringCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFilteringCheckBoxValueChanged, true);
            app.UseFilteringCheckBox.Text = 'Use Filtering';
            app.STEPButton = uibutton(app.AlgorithmsPanel, 'push');
            app.STEPButton.ButtonPushedFcn = createCallbackFcn(app, @STEPButtonPushed, true);
            app.STEPButton.Text = 'STEP';
            app.SaveCheckBox = uicheckbox(app.AlgorithmsPanel);
            app.SaveCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveCheckBoxValueChanged, true);
            app.SaveCheckBox.Text = 'Save';
            app.SaveFilenameEditFieldLabel = uilabel(app.AlgorithmsPanel);
            app.SaveFilenameEditFieldLabel.Text = 'Save Filename';
            app.SaveFilenameEditField = uieditfield(app.AlgorithmsPanel, 'text');
            app.AppStatusLabel = uilabel(app.AlgorithmsPanel);
            app.AppStatusLabel.Text = 'Idle';
            app.PlotTimeCheckBox = uicheckbox(app.AlgorithmsPanel);
            app.PlotTimeCheckBox.Text = 'Plot Time';
            app.PlotSpectrumCheckBox = uicheckbox(app.AlgorithmsPanel);
            app.PlotSpectrumCheckBox.Text = 'Plot Spectrum';
            app.PlottingPanel = uipanel(app.ProcessingTab);
            app.PlottingPanel.Title = 'Plotting';
            app.TabGroup2 = uitabgroup(app.PlottingPanel);
            app.CrossAmbiguityFunctionTab = uitab(app.TabGroup2);
            app.CrossAmbiguityFunctionTab.Title = 'Cross Ambiguity Function';
            app.UIAxes_CAF = uiaxes(app.CrossAmbiguityFunctionTab);
            title(app.UIAxes_CAF, 'CAF')
            xlabel(app.UIAxes_CAF, 'X')
            ylabel(app.UIAxes_CAF, 'Y')
            zlabel(app.UIAxes_CAF, 'Z')
            app.UIAxes_xcorr = uiaxes(app.CrossAmbiguityFunctionTab);
            title(app.UIAxes_xcorr, 'Cross Correlation')
            xlabel(app.UIAxes_xcorr, 'X')
            ylabel(app.UIAxes_xcorr, 'Y')
            zlabel(app.UIAxes_xcorr, 'Z')
            app.UITimeAxes = uiaxes(app.CrossAmbiguityFunctionTab);
            title(app.UITimeAxes, 'Title')
            xlabel(app.UITimeAxes, 'X')
            ylabel(app.UITimeAxes, 'Y')
            zlabel(app.UITimeAxes, 'Z')
            app.UISpectrumAxes = uiaxes(app.CrossAmbiguityFunctionTab);
            title(app.UISpectrumAxes, 'Title')
            xlabel(app.UISpectrumAxes, 'X')
            ylabel(app.UISpectrumAxes, 'Y')
            zlabel(app.UISpectrumAxes, 'Z')
            app.CafDelayForwardEditField = uieditfield(app.CrossAmbiguityFunctionTab, 'numeric');
            app.CafDelayForwardEditField.ValueChangedFcn = createCallbackFcn(app, @CafDelayForwardEditFieldValueChanged, true);
            app.CafDelayForwardEditField.Value = 30;
            app.CafDelayForwardEditFieldLabel = uilabel(app.CrossAmbiguityFunctionTab);
            app.CafDelayForwardEditFieldLabel.Text = 'Caf Delay Forward';
            app.CafDelayBackwardEditField = uieditfield(app.CrossAmbiguityFunctionTab, 'numeric');
            app.CafDelayBackwardEditField.ValueChangedFcn = createCallbackFcn(app, @CafDelayForwardEditFieldValueChanged, true);
            app.CafDelayBackwardEditFieldLabel = uilabel(app.CrossAmbiguityFunctionTab);
            app.CafDelayBackwardEditFieldLabel.Text = 'Caf Delay Backward';
            app.FFTSizeEditField = uieditfield(app.CrossAmbiguityFunctionTab, 'numeric');
            app.FFTSizeEditField.ValueChangedFcn = createCallbackFcn(app, @CafDelayForwardEditFieldValueChanged, true);
            app.FFTSizeEditFieldLabel = uilabel(app.CrossAmbiguityFunctionTab);
            app.FFTSizeEditFieldLabel.Text = 'FFT Size';
            app.CafVelocityRangeEditField = uieditfield(app.CrossAmbiguityFunctionTab, 'numeric');
            app.CafVelocityRangeEditField.ValueChangedFcn = createCallbackFcn(app, @CafDelayForwardEditFieldValueChanged, true);
            app.CafVelocityRangeEditField.Value = 200;
            app.CafVelocityRangeLabel = uilabel(app.CrossAmbiguityFunctionTab);
            app.CafVelocityRangeLabel.Text = 'Caf Velocity Range (+/-)';
            app.EditField = uieditfield(app.CrossAmbiguityFunctionTab, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @CafDelayForwardEditFieldValueChanged, true);
            app.EditFieldLabel = uilabel(app.CrossAmbiguityFunctionTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.CorrelationDelayEditField = uieditfield(app.CrossAmbiguityFunctionTab, 'numeric');
            app.CorrelationDelayEditField.ValueChangedFcn = createCallbackFcn(app, @CafDelayForwardEditFieldValueChanged, true);
            app.CorrelationDelayEditField.Value = 1000;
            app.CorrelationdelayLabel = uilabel(app.CrossAmbiguityFunctionTab);
            app.CorrelationdelayLabel.Text = {'Correlation'; 'Delay'};
            app.ParamsLog = uitextarea(app.ProcessingTab);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.sim_ParametersPanel = uipanel(app.SimulationTab);
            app.sim_ParametersPanel.Title = 'Parameters';
            app.SignalTypeDropDownLabel = uilabel(app.sim_ParametersPanel);
            app.SignalTypeDropDownLabel.Text = 'Signal Type';
            app.sim_SignalTypeDropDown = uidropdown(app.sim_ParametersPanel);
            app.sim_SignalTypeDropDown.Items = {'FM', 'DAB+', 'DVB-T'};
            app.sim_SignalTypeDropDown.Value = 'FM';
            app.FsEditField_2Label = uilabel(app.sim_ParametersPanel);
            app.FsEditField_2Label.Text = 'Fs';
            app.sim_FsEditField = uieditfield(app.sim_ParametersPanel, 'numeric');
            app.sim_FsEditField.ValueChangedFcn = createCallbackFcn(app, @sim_FsEditFieldValueChanged, true);
            app.sim_FsEditField.Value = 8600000;
            app.FcEditField_2Label = uilabel(app.sim_ParametersPanel);
            app.FcEditField_2Label.Text = 'Fc';
            app.sim_FcEditField = uieditfield(app.sim_ParametersPanel, 'numeric');
            app.sim_FcEditField.Value = 500000000;
            app.IntegrationTimesEditFieldLabel = uilabel(app.sim_ParametersPanel);
            app.IntegrationTimesEditFieldLabel.Text = 'Integration Time [s]';
            app.sim_IntegrationTimesEditField = uieditfield(app.sim_ParametersPanel, 'numeric');
            app.sim_IntegrationTimesEditField.Value = 1;
            app.sim_TargetsPanel = uipanel(app.SimulationTab);
            app.sim_TargetsPanel.Title = 'Targets';
            app.Range1mEditFieldLabel = uilabel(app.sim_TargetsPanel);
            app.Range1mEditFieldLabel.Text = 'Range 1 [m]';
            app.Range1mEditField = uieditfield(app.sim_TargetsPanel, 'numeric');
            app.Range2mEditFieldLabel = uilabel(app.sim_TargetsPanel);
            app.Range2mEditFieldLabel.Text = 'Range 2 [m]';
            app.Range2mEditField = uieditfield(app.sim_TargetsPanel, 'numeric');
            app.Velocity1msEditFieldLabel = uilabel(app.sim_TargetsPanel);
            app.Velocity1msEditFieldLabel.Text = 'Velocity 1 [m/s]';
            app.Velocity1msEditField = uieditfield(app.sim_TargetsPanel, 'numeric');
            app.Velocity2msEditFieldLabel = uilabel(app.sim_TargetsPanel);
            app.Velocity2msEditFieldLabel.Text = 'Velocity 2 [m/s]';
            app.Velocity2msEditField = uieditfield(app.sim_TargetsPanel, 'numeric');
            app.Attenuation1dBEditFieldLabel = uilabel(app.sim_TargetsPanel);
            app.Attenuation1dBEditFieldLabel.Text = 'Attenuation 1 [dB]';
            app.Attenuation1dBEditField = uieditfield(app.sim_TargetsPanel, 'numeric');
            app.Attenuation2dBEditFieldLabel = uilabel(app.sim_TargetsPanel);
            app.Attenuation2dBEditFieldLabel.Text = 'Attenuation 2 [dB]';
            app.Attenuation2dBEditField = uieditfield(app.sim_TargetsPanel, 'numeric');
            app.sim_StartButton = uibutton(app.SimulationTab, 'push');
            app.sim_StartButton.ButtonPushedFcn = createCallbackFcn(app, @sim_StartButtonPushed, true);
            app.sim_StartButton.Text = 'Start';
            app.MapTab = uitab(app.TabGroup);
            app.MapTab.Title = 'Map';
            app.CLEANTab = uitab(app.TabGroup);
            app.CLEANTab.Title = 'CLEAN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PassiveRadarApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end